classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Image2             matlab.ui.control.Image
        MonitorizarePulsinTimpRealLabel  matlab.ui.control.Label
        BPMEditField       matlab.ui.control.NumericEditField
        BPMEditFieldLabel  matlab.ui.control.Label
        TahicardieButton   matlab.ui.control.Button
        BradicardieButton  matlab.ui.control.Button
        NormalButton       matlab.ui.control.Button
        Image              matlab.ui.control.Image
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
         arduinoCom
         timerObj
         numSeconds = 60; % Numărul de secunde pentru buffer
         pulsData;        % Buffer pentru datele pulsului
         times;           % Timpul
         puls;
    end
    
    methods (Access = private)
        
        function updateUI(app)
            % Citirea și afișarea BPM
            if app.arduinoCom.BytesAvailable > 0

                raw_data = fscanf(app.arduinoCom);
                % Elimina orice caracter non-cifra (inclusiv newline si spatiile)
                raw_data = regexp(raw_data, '\d+', 'match');
                raw_data = strjoin(raw_data, '');
               
                nr=0;
                for i=1:length(raw_data)
                    if raw_data(i)>='0' && raw_data(i)<='9'
                        nr=nr*10+double(raw_data(i))-double('0');
                    end
                end

                % Adaugă valoarea la buffer
                a=randi([6 9]);
                b=randi([-4 0]);
                c=randi([-17 -10]);
                d=randi([0 3]);
                e=randi([-2 0]);
                app.pulsData = [app.pulsData(8:end), a, b, nr, c, d, e, 0];
                app.puls=[app.puls(2:end),nr];

                % Actualizează graficul
                plot(app.UIAxes, app.times, app.puls);
                
                
                % Update BPMEditFields
                data=sum(app.puls)/sum(app.puls~=0);
                app.BPMEditField.Value = data;


                % Update button visibility based on the data
                if data >= 100
                    app.TahicardieButton.Visible = 'on';
                    app.TahicardieButton.BackgroundColor = 'red'; % Butonul 3 roșu
                    app.BradicardieButton.Visible = 'off';
                    app.NormalButton.Visible = 'off';
                elseif data <= 50
                    app.TahicardieButton.Visible = 'off';
                    app.BradicardieButton.Visible = 'on';
                    app.BradicardieButton.BackgroundColor = 'yellow'; % Butonul 2 galben
                    app.NormalButton.Visible = 'off';
                else
                    app.TahicardieButton.Visible = 'off';
                    app.BradicardieButton.Visible = 'off';
                    app.NormalButton.Visible = 'on';
                    app.NormalButton.BackgroundColor = 'green'; % Butonul 1 verde
                end
            end
        end

        function initializeSerialConnection(app)
            % Configurarea și deschiderea portului serial
            app.arduinoCom = serial('COM3', 'BaudRate', 9600);
        end
    end
    
    methods (Access = public)
        
        % Method to initialize Arduino connection and set timer
        function initializeArduinoConnection(app)


            oldSerial = instrfind('Port', 'COM3');
            if ~isempty(oldSerial)
               fclose(oldSerial);
               delete(oldSerial);
            end

            % Initialize Arduino serial connection
            app.initializeSerialConnection();
            fopen(app.arduinoCom);

            % Setează limitele axei X și Y pentru UIAxes
            app.UIAxes.YLim = [-50 150];

            % Inițializează buffer-ul și timpul
            app.pulsData = zeros(1, app.numSeconds);
            app.times = 1:app.numSeconds;
            app.puls=app.pulsData;

            plot(app.UIAxes, app.times, app.pulsData);

            %Create and configure the timer
            app.timerObj = timer;
            app.timerObj.ExecutionMode = 'fixedRate';
            app.timerObj.Period = 1; % Timer period in seconds
            app.timerObj.TimerFcn = @(~,~) app.updateUI();
            start(app.timerObj);
           
        end
    end
    

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Timp (secunde)')
            ylabel(app.UIAxes, 'Batai pe minut')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [-50 0 50 100];
            app.UIAxes.YTickLabel = {'-50'; '0'; '50'; '100'};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'pngwing.com.png');
            app.NormalButton = uibutton(app.UIFigure, 'push');
            app.NormalButton.Visible = 'off';
            app.NormalButton.Text = 'Normal';
            app.BradicardieButton = uibutton(app.UIFigure, 'push');
            app.BradicardieButton.Visible = 'off';
            app.BradicardieButton.Text = 'Bradicardie';
            app.TahicardieButton = uibutton(app.UIFigure, 'push');
            app.TahicardieButton.Visible = 'off';
            app.TahicardieButton.Text = 'Tahicardie';
            app.BPMEditFieldLabel = uilabel(app.UIFigure);
            app.BPMEditFieldLabel.Text = 'BPM';
            app.BPMEditField = uieditfield(app.UIFigure, 'numeric');
            app.MonitorizarePulsinTimpRealLabel = uilabel(app.UIFigure);
            app.MonitorizarePulsinTimpRealLabel.Text = 'Monitorizare Puls in Timp Real';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'pngwing.com (1).png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end